classdef GUI_v0_07b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MissionControl              matlab.ui.Figure
        ProceduresPanel             matlab.ui.container.Panel
        PurgeSwitch                 matlab.ui.control.Switch
        IgniterSwitch               matlab.ui.control.Switch
        EngineStartButton           matlab.ui.control.Button
        EngineShutdownButton        matlab.ui.control.Button
        ArmFillButton               matlab.ui.control.StateButton
        ArmStartButton              matlab.ui.control.StateButton
        ProceduresLabel             matlab.ui.control.Label
        N2FillButton                matlab.ui.control.StateButton
        OxFillButton                matlab.ui.control.StateButton
        EthanolFillButton           matlab.ui.control.StateButton
        OxFillStateLabel            matlab.ui.control.Label
        EthanolFillStateLabel       matlab.ui.control.Label
        N2FillStateLabel            matlab.ui.control.Label
        IVCTitleDivider_2           matlab.ui.control.Label
        IVCTitleDivider_3           matlab.ui.control.Label
        IVCPanel                    matlab.ui.container.Panel
        IVCSwitch                   matlab.ui.control.Switch
        IVCSwitchLockedLabel        matlab.ui.control.Label
        IVCSwitchUnlockedLabel      matlab.ui.control.Label
        IVCLabel                    matlab.ui.control.Label
        OxFlowGraph                 matlab.ui.control.UIAxes
        EthanolFlowGraph            matlab.ui.control.UIAxes
        psiLampLabel                matlab.ui.control.Label
        EthanolPressureReading      matlab.ui.control.Lamp
        psiLamp_2Label              matlab.ui.control.Label
        OxPressureReading           matlab.ui.control.Lamp
        EthanolFlowReading          matlab.ui.control.Label
        OxFlowReading               matlab.ui.control.Label
        MixtureRatioEditFieldLabel  matlab.ui.control.Label
        MixtureRatio                matlab.ui.control.EditField
        EthanolPressureGraph        matlab.ui.control.UIAxes
        OxPressureGraph             matlab.ui.control.UIAxes
        Temp1Label                  matlab.ui.control.Label
        TempGauge1                  matlab.ui.control.EditField
        Temp2Label                  matlab.ui.control.Label
        TempGauge2                  matlab.ui.control.EditField
        Temp3Label                  matlab.ui.control.Label
        TempGauge3                  matlab.ui.control.EditField
        Temp4Label                  matlab.ui.control.Label
        TempGauge4                  matlab.ui.control.EditField
        StatusReadyLabel            matlab.ui.control.Label
        psiLamp_2Label_2            matlab.ui.control.Label
        N2PressureReading           matlab.ui.control.Lamp
        N2PressureGraph             matlab.ui.control.UIAxes
        diagramPanel                matlab.ui.container.Panel
        Diagram                     matlab.ui.control.StateButton
        SV1                         matlab.ui.control.StateButton
        SV2                         matlab.ui.control.StateButton
        SV3                         matlab.ui.control.StateButton
        SV4                         matlab.ui.control.StateButton
        SV5                         matlab.ui.control.StateButton
        SV6                         matlab.ui.control.StateButton
        SV7                         matlab.ui.control.StateButton
        SV8                         matlab.ui.control.StateButton
        SV9                         matlab.ui.control.StateButton
        BV1                         matlab.ui.control.StateButton
        BV2                         matlab.ui.control.StateButton
        BV3                         matlab.ui.control.StateButton
        BV4                         matlab.ui.control.StateButton
        BV5                         matlab.ui.control.StateButton
    end


    properties (Access = private)
        valveOnColor = [0 1 0];
        valveOffColor = [0.5 0.5 0.5];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Global variables
            
            
            % serial connection setup
            
        end

        % Value changed function: ArmFillButton
        function ArmFillButtonPressed(app, event)
            if (app.ArmFillButton.Value)                        
                % Arm fill procedures
                app.N2FillButton.Enable = 'on';
                app.OxFillButton.Enable = 'on';
                app.EthanolFillButton.Enable = 'on';
                app.N2FillStateLabel.Enable = 'on';
                app.OxFillStateLabel.Enable = 'on';
                app.EthanolFillStateLabel.Enable = 'on';
            else                                                
                % Disarm fill procedures
                app.N2FillButton.Enable = 'off';
                app.OxFillButton.Enable = 'off';
                app.EthanolFillButton.Enable = 'off';
                app.N2FillStateLabel.Enable = 'off';
                app.OxFillStateLabel.Enable = 'off';
                app.EthanolFillStateLabel.Enable = 'off';
            end
        end

        % Value changed function: N2FillButton
        function N2FillButtonPressed(app, event)
            if (app.N2FillButton.Value)
                app.N2FillStateLabel.FontColor = [0.47 0.67 0.19];
                app.N2FillStateLabel.FontWeight = 'bold';
                app.N2FillStateLabel.Text = 'On';
                % Start N2 fill
            else
                app.N2FillStateLabel.FontColor = 'red';
                app.N2FillStateLabel.FontWeight = 'normal';
                app.N2FillStateLabel.Text = 'Off';
                % Stop N2 Fill
            end
        end

        % Value changed function: OxFillButton
        function OxFillButtonPressed(app, event)
            if (app.OxFillButton.Value)                                     
                app.OxFillStateLabel.FontColor = [0.47 0.67 0.19];
                app.OxFillStateLabel.FontWeight = 'bold';
                app.OxFillStateLabel.Text = 'On';
                % Start Ox fill
            else                                                            
                app.OxFillStateLabel.FontColor = 'red';
                app.OxFillStateLabel.FontWeight = 'normal';
                app.OxFillStateLabel.Text = 'Off';
                % Stop Ox Fill
            end
        end

        % Value changed function: EthanolFillButton
        function EthanolFillButtonPressed(app, event)
            if (app.EthanolFillButton.Value)                                
                app.EthanolFillStateLabel.FontColor = [0.47 0.67 0.19];
                app.EthanolFillStateLabel.FontWeight = 'bold';
                app.EthanolFillStateLabel.Text = 'On';
                % Start Ethanol fill
            else                                                            
                app.EthanolFillStateLabel.FontColor = 'red';
                app.EthanolFillStateLabel.FontWeight = 'normal';
                app.EthanolFillStateLabel.Text = 'Off';
                % Stop Ethanol Fill
            end
        end

        % Value changed function: ArmStartButton
        function ArmStartButtonPressed(app, event)
            if (app.ArmStartButton.Value)
                % Arm engine procedures
                app.IgniterSwitch.Enable = 'on';
                app.PurgeSwitch.Enable = 'on';
                app.EngineStartButton.Enable = 'on';
                app.EngineShutdownButton.Enable = 'on';
            else                                               
                % Disarm engine procedures
                app.IgniterSwitch.Enable = 'off';
                app.PurgeSwitch.Enable = 'off';
                app.EngineStartButton.Enable = 'off';
                app.EngineShutdownButton.Enable = 'off';
            end
        end

        % Value changed function: IVCSwitch
        function IVCSwitchValueChanged(app, event)
            if (app.IVCSwitch.Value == '1')     
                % Unlock independent valve control panel
                app.IVCSwitchUnlockedLabel.FontWeight = 'bold';
                app.IVCSwitchUnlockedLabel.FontColor = [0.47 0.67 0.19];
                app.IVCSwitchLockedLabel.FontWeight = 'normal';
                app.IVCSwitchLockedLabel.FontColor = 'black';
            else                                                            
                % Lock independent valve control panel
                app.IVCSwitchUnlockedLabel.FontWeight = 'normal';
                app.IVCSwitchUnlockedLabel.FontColor = 'black';
                app.IVCSwitchLockedLabel.FontWeight = 'bold';
                app.IVCSwitchLockedLabel.FontColor = 'red';
            end
        end

        % Close request function: MissionControl
        function MissionControlCloseRequest(app, event)
            answer1 = questdlg('Really quit?', 'Sanity Check', 'Yes', 'No', 'No');
            switch answer1
                case 'Yes'
                    answer2 = questdlg('REALLY REALLY quit?', 'Sanity Check', 'Yes', 'No', 'No');
                    % make second prompt show up at a different position from the first
                    switch answer2
                        case 'Yes'
                            delete(app)
                    end
            end
        end

        % Value changed function: SV1
        function SV1ValueChanged(app, event)
            % add confirmation prompt
            if( app.SV1.Value == 1 )
                app.SV1.BackgroundColor = app.valveOnColor;
            else
                app.SV1.BackgroundColor = app.valveOffColor;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MissionControl = uifigure('Visible', 'off');
            app.MissionControl.Name = 'Mission Control';
            app.MissionControl.CloseRequestFcn = createCallbackFcn(app, @MissionControlCloseRequest, true);
            app.ProceduresPanel = uipanel(app.MissionControl);
            app.PurgeSwitch = uiswitch(app.ProceduresPanel, 'slider');
            app.PurgeSwitch.Items = {'1', '0'};
            app.PurgeSwitch.Enable = 'off';
            app.PurgeSwitch.Value = '1';
            app.IgniterSwitch = uiswitch(app.ProceduresPanel, 'slider');
            app.IgniterSwitch.Items = {'0', '1'};
            app.IgniterSwitch.Enable = 'off';
            app.IgniterSwitch.Value = '0';
            app.EngineStartButton = uibutton(app.ProceduresPanel, 'push');
            app.EngineStartButton.Enable = 'off';
            app.EngineStartButton.Text = 'Engine Start';
            app.EngineShutdownButton = uibutton(app.ProceduresPanel, 'push');
            app.EngineShutdownButton.Enable = 'off';
            app.EngineShutdownButton.Text = 'Engine Shutdown';
            app.ArmFillButton = uibutton(app.ProceduresPanel, 'state');
            app.ArmFillButton.ValueChangedFcn = createCallbackFcn(app, @ArmFillButtonPressed, true);
            app.ArmFillButton.Text = 'Arm Fill';
            app.ArmFillButton.Value = true;
            app.ArmStartButton = uibutton(app.ProceduresPanel, 'state');
            app.ArmStartButton.ValueChangedFcn = createCallbackFcn(app, @ArmStartButtonPressed, true);
            app.ArmStartButton.Text = 'Arm Start';
            app.ProceduresLabel = uilabel(app.ProceduresPanel);
            app.ProceduresLabel.Text = 'Procedures';
            app.N2FillButton = uibutton(app.ProceduresPanel, 'state');
            app.N2FillButton.ValueChangedFcn = createCallbackFcn(app, @N2FillButtonPressed, true);
            app.N2FillButton.Text = 'Fill N2';
            app.OxFillButton = uibutton(app.ProceduresPanel, 'state');
            app.OxFillButton.ValueChangedFcn = createCallbackFcn(app, @OxFillButtonPressed, true);
            app.OxFillButton.Text = 'Fill Ox';
            app.EthanolFillButton = uibutton(app.ProceduresPanel, 'state');
            app.EthanolFillButton.ValueChangedFcn = createCallbackFcn(app, @EthanolFillButtonPressed, true);
            app.EthanolFillButton.Text = 'Fill Ethanol';
            app.OxFillStateLabel = uilabel(app.ProceduresPanel);
            app.OxFillStateLabel.Text = 'Off';
            app.EthanolFillStateLabel = uilabel(app.ProceduresPanel);
            app.EthanolFillStateLabel.Text = 'Off';
            app.N2FillStateLabel = uilabel(app.ProceduresPanel);
            app.N2FillStateLabel.Text = 'Off';
            app.IVCTitleDivider_2 = uilabel(app.ProceduresPanel);
            app.IVCTitleDivider_2.Enable = 'off';
            app.IVCTitleDivider_2.Text = '________________________';
            app.IVCTitleDivider_3 = uilabel(app.ProceduresPanel);
            app.IVCTitleDivider_3.Enable = 'off';
            app.IVCTitleDivider_3.Text = '________________________';
            app.IVCPanel = uipanel(app.MissionControl);
            app.IVCSwitch = uiswitch(app.IVCPanel, 'slider');
            app.IVCSwitch.Items = {'0', '1'};
            app.IVCSwitch.ValueChangedFcn = createCallbackFcn(app, @IVCSwitchValueChanged, true);
            app.IVCSwitch.Value = '0';
            app.IVCSwitchLockedLabel = uilabel(app.IVCPanel);
            app.IVCSwitchLockedLabel.Text = 'Locked';
            app.IVCSwitchUnlockedLabel = uilabel(app.IVCPanel);
            app.IVCSwitchUnlockedLabel.Text = 'Unlocked';
            app.IVCLabel = uilabel(app.IVCPanel);
            app.IVCLabel.Text = 'Independent Valve Control';
            app.OxFlowGraph = uiaxes(app.MissionControl);
            title(app.OxFlowGraph, 'OX Flow Rate')
            xlabel(app.OxFlowGraph, ' ')
            app.OxFlowGraph.XTickLabel = '';
            app.OxFlowGraph.YTickLabel = '';
            app.EthanolFlowGraph = uiaxes(app.MissionControl);
            title(app.EthanolFlowGraph, 'Ethanol Flow Rate')
            xlabel(app.EthanolFlowGraph, ' ')
            app.EthanolFlowGraph.XTickLabel = '';
            app.EthanolFlowGraph.YTickLabel = '';
            app.psiLampLabel = uilabel(app.MissionControl);
            app.psiLampLabel.Text = '0 psi';
            app.EthanolPressureReading = uilamp(app.MissionControl);
            app.psiLamp_2Label = uilabel(app.MissionControl);
            app.psiLamp_2Label.Text = '0 psi';
            app.OxPressureReading = uilamp(app.MissionControl);
            app.EthanolFlowReading = uilabel(app.MissionControl);
            app.EthanolFlowReading.Text = '0 kg/s';
            app.OxFlowReading = uilabel(app.MissionControl);
            app.OxFlowReading.Text = '0 kg/s';
            app.MixtureRatioEditFieldLabel = uilabel(app.MissionControl);
            app.MixtureRatioEditFieldLabel.Text = 'Mixture Ratio';
            app.MixtureRatio = uieditfield(app.MissionControl, 'text');
            app.MixtureRatio.Editable = 'off';
            app.MixtureRatio.Value = '0.00';
            app.EthanolPressureGraph = uiaxes(app.MissionControl);
            title(app.EthanolPressureGraph, 'EtOH Tank Pressure')
            xlabel(app.EthanolPressureGraph, ' ')
            app.EthanolPressureGraph.XTickLabel = '';
            app.EthanolPressureGraph.YTickLabel = '';
            app.OxPressureGraph = uiaxes(app.MissionControl);
            title(app.OxPressureGraph, 'OX Tank Pressure')
            xlabel(app.OxPressureGraph, ' ')
            app.OxPressureGraph.XTickLabel = '';
            app.OxPressureGraph.YTickLabel = '';
            app.Temp1Label = uilabel(app.MissionControl);
            app.Temp1Label.Text = 'Temp1';
            app.TempGauge1 = uieditfield(app.MissionControl, 'text');
            app.TempGauge1.Editable = 'off';
            app.TempGauge1.Value = '0.00C';
            app.Temp2Label = uilabel(app.MissionControl);
            app.Temp2Label.Text = 'Temp2';
            app.TempGauge2 = uieditfield(app.MissionControl, 'text');
            app.TempGauge2.Editable = 'off';
            app.TempGauge2.Value = '0.00C';
            app.Temp3Label = uilabel(app.MissionControl);
            app.Temp3Label.Text = 'Temp3';
            app.TempGauge3 = uieditfield(app.MissionControl, 'text');
            app.TempGauge3.Editable = 'off';
            app.TempGauge3.Value = '0.00C';
            app.Temp4Label = uilabel(app.MissionControl);
            app.Temp4Label.Text = 'Temp4';
            app.TempGauge4 = uieditfield(app.MissionControl, 'text');
            app.TempGauge4.Editable = 'off';
            app.TempGauge4.Value = '0.00C';
            app.StatusReadyLabel = uilabel(app.MissionControl);
            app.StatusReadyLabel.Text = 'Status: Ready';
            app.psiLamp_2Label_2 = uilabel(app.MissionControl);
            app.psiLamp_2Label_2.Text = '0 psi';
            app.N2PressureReading = uilamp(app.MissionControl);
            app.N2PressureGraph = uiaxes(app.MissionControl);
            title(app.N2PressureGraph, 'N2 Tank Pressure')
            xlabel(app.N2PressureGraph, ' ')
            app.N2PressureGraph.XTickLabel = '';
            app.N2PressureGraph.YTickLabel = '';
            app.diagramPanel = uipanel(app.MissionControl);
            app.Diagram = uibutton(app.diagramPanel, 'state');
            app.Diagram.Icon = '0. System Diagram.png';
            app.Diagram.Text = '';
            app.SV1 = uibutton(app.diagramPanel, 'state');
            app.SV1.ValueChangedFcn = createCallbackFcn(app, @SV1ValueChanged, true);
            app.SV1.Text = '';
            app.SV2 = uibutton(app.diagramPanel, 'state');
            app.SV2.Text = '';
            app.SV2.Value = true;
            app.SV3 = uibutton(app.diagramPanel, 'state');
            app.SV3.Text = '';
            app.SV4 = uibutton(app.diagramPanel, 'state');
            app.SV4.Text = '';
            app.SV4.Value = true;
            app.SV5 = uibutton(app.diagramPanel, 'state');
            app.SV5.Text = '';
            app.SV6 = uibutton(app.diagramPanel, 'state');
            app.SV6.Text = '';
            app.SV7 = uibutton(app.diagramPanel, 'state');
            app.SV7.Text = '';
            app.SV7.Value = true;
            app.SV8 = uibutton(app.diagramPanel, 'state');
            app.SV8.Text = '';
            app.SV9 = uibutton(app.diagramPanel, 'state');
            app.SV9.Text = '';
            app.BV1 = uibutton(app.diagramPanel, 'state');
            app.BV1.Text = '';
            app.BV2 = uibutton(app.diagramPanel, 'state');
            app.BV2.Text = '';
            app.BV2.Value = true;
            app.BV3 = uibutton(app.diagramPanel, 'state');
            app.BV3.Text = '';
            app.BV3.Value = true;
            app.BV4 = uibutton(app.diagramPanel, 'state');
            app.BV4.Text = '';
            app.BV5 = uibutton(app.diagramPanel, 'state');
            app.BV5.Text = '';
            app.BV5.Value = true;
            % Show the figure after all components are created
            app.MissionControl.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v0_07b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MissionControl)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MissionControl)
        end
    end
end